classdef Comm_sys_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AMModulationDropDownLabel    matlab.ui.control.Label
        AMModulationDropDown         matlab.ui.control.DropDown
        ChannelDropDownLabel         matlab.ui.control.Label
        ChannelDropDown              matlab.ui.control.DropDown
        DemodulationDropDownLabel    matlab.ui.control.Label
        DemodulationDropDown         matlab.ui.control.DropDown
        InputFilenameEditFieldLabel  matlab.ui.control.Label
        InputFilenameEditField       matlab.ui.control.EditField
        MessageButton                matlab.ui.control.Button
        ResultsTextAreaLabel         matlab.ui.control.Label
        ResultsTextArea              matlab.ui.control.TextArea
        ResultsButton                matlab.ui.control.Button
        ModulatedSignalButton        matlab.ui.control.Button
        DemodulatedandcompareButton  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file_name = "";
        modulation = 1;
        channel = 1;
        demodulation = 1;
        time = 1;
        amp = 1;
        butt = "Message"
        n = 0;
        freq_m = 0;
        freq_sam = 0;
        freq_car = 0;
        Amp_c = 0;
        mod = 0;
        demod = 0;
        sig_pow = 0;
        noise_pow = 0;
        
    end
    
    methods (Access = private)
        
        function tt = plt(app, Amp, time, f_m, f_)
            t = time;
            mess = Amp;
            fm = f_m;
            f = f_;
            plot(app.UIAxes, t, mess, '-r')
            app.UIAxes.XLim = [0, 10/fm];        
               
                
            spec_mess = abs(fftshift(fft(mess)));
            plot(app.UIAxes2, f, spec_mess, '-b');
            app.UIAxes2.XLim = [f(1),f(length(f)) ];
        end
        
        function [c, r] = rc_envelope(app)
            fc = app.freq_car;
            cap = 0.1e-6;
            
            rl = 15/(fc*cap);
            rl = rl/1000;
            r = rl;
            c = cap * 100e4;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputFilenameEditField
        function InputFilenameEditFieldValueChanged(app, event)
            value = app.InputFilenameEditField.Value;
            app.file_name = value;
            disp(value)
            data = load(app.file_name);
            app.time = data(:, 1);
            app.amp = data(:,2);
            app.n = length(app.time);
            disp("The file has been loaded")
            t = app.time;
            mess = app.amp;
            app.freq_sam = 1/(t(2)-t(1));
            fs = app.freq_sam;
            app.freq_car = app.freq_sam/10;
            spec = abs(fft(mess));
            spec = spec(ceil(app.n/2): app.n);
            s = '';
            app.sig_pow = sum(mess.^2)/app.n;
            disp(app.sig_pow)

            [~,loc] = findpeaks(spec);
            app.freq_m = fs/2 - loc(1)*fs/app.n + 2;
            disp(app.freq_m)
            if app.freq_car < 2*app.freq_m
                app.freq_car = (2*app.freq_m + 3*app.freq_sam/2)/4;
                s = " Optimum modulation isn't possible as Fs and Fm are very close";
            end
            app.Amp_c = max(mess);
            app.ResultsTextArea.Value = 'fm = ' + string(app.freq_m) + ' and fc = ' + string(app.freq_car) + s;
            
        end

        % Value changed function: AMModulationDropDown
        function AMModulationDropDownValueChanged(app, event)
            value = app.AMModulationDropDown.Value;
            app.modulation = value;
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            app.channel = value;
        end

        % Value changed function: DemodulationDropDown
        function DemodulationDropDownValueChanged(app, event)
            value = app.DemodulationDropDown.Value;
            app.demodulation = value;
        end

        % Callback function
        function PLOTButtonGroupSelectionChanged(app, event)
            selectedButton = app.PLOTButtonGroup.SelectedObject;
            app.butt = selectedButton.Text;
            disp(app.butt)
        end

        % Button pushed function: MessageButton
        function MessageButtonPushed(app, event)
            t = app.time;
            mess = app.amp;
            fm = app.freq_m;
            n_len = app.n;
            fs = app.freq_sam;
            f = (-n_len/2:n_len/2 - 1) * (fs/n_len);
            app.plt(mess, t,fm, f);
           
          
        end

        % Button pushed function: ResultsButton
        function ResultsButtonPushed(app, event)
            if app.channel == 3 || app.channel == 4
                app.ResultsTextArea.Value = 'SNR is ' + string(10*log10(app.sig_pow/app.noise_pow)) + ' dB' ;
            else
                app.ResultsTextArea.Value = "SNR cannot be calculated as channel is noiseless";
            end
            
            
        end

        % Button pushed function: ModulatedSignalButton
        function ModulatedSignalButtonPushed(app, event)
            t = app.time;
            mess = app.amp;
            n_len = app.n;
            fs = app.freq_sam;
            fc = app.freq_car;
            Ac = app.Amp_c + 1;
            fm = app.freq_m;
            f = (-n_len/2:n_len/2 - 1) * (fs/n_len);
            
            if app.modulation == 1
                %DSB-C
                modu = (Ac + mess).*cos(2*pi*fc.*t);
            elseif app.modulation == 2
                %DSB-SC
                modu = mess.*sin(2*pi*fc.*t);
            elseif app.modulation == 3
                %SSB-USB
                m_cap = hilbert(mess);
                modu = mess.*cos(2*pi*fc.*t) - m_cap.*sin(2*pi*fc.*t);
                app.ResultsTextArea.Value = 'Imaginary components ignored while plotting';
                
            else
                %SSB-LSB
                m_cap = hilbert(mess);
                modu = mess.*cos(2*pi*fc.*t) + m_cap.*sin(2*pi*fc.*t);
                app.ResultsTextArea.Value = 'Imaginary components ignored while plotting';
            end
            
            
            if app.channel == 1
                %Ideal lossless, noiseless
                app.mod = modu;
                app.plt(app.mod, t, fm, f);
                
            elseif app.channel == 2
                %Lossy, Noiseless
                app.mod = modu/sqrt(2);
                app.plt(app.mod, t, fm, f);
                
            elseif app.channel == 3
                %Lossless, AWGN
                noise = randn(n_len, 1);
%                 noise = 0.1.*noise;
                app.mod = modu + noise;
                app.noise_pow = fm.*(mean(abs(fft(noise)))^2);
                disp(app.noise_pow)
                app.plt(app.mod, t, fm, f);
            else
                %Lossy, AWGN
                noise = randn(n_len, 1);
%                 noise = 0.1.*noise;
                app.mod = app.mod/sqrt(2);
                app.mod = modu + noise;
                app.noise_pow = fm.*(mean(abs(fft(noise)))^2);
                app.plt(app.mod, t, fm, f);
                
            end


        end

        % Button pushed function: DemodulatedandcompareButton
        function DemodulatedandcompareButtonPushed(app, event)
            
            t = app.time;
            mess = app.amp;
%             n_len = app.n;
            fs = app.freq_sam;
            fc = app.freq_car;
            Ac = app.Amp_c + 1;
            fm = app.freq_m;
            
            if app.demodulation == 1
                %Synchronous Detector
                if app.modulation == 2
                    y = app.mod.*sin(2*pi*fc.*t);
                else
                    y = app.mod.*cos(2*pi*fc.*t);
                    
                end
                
                fn = fs/2;
                [ ord, Wn ] = buttord(fm/fn, fc/fn, 2, 50);
                [b, a] = butter(ord, Wn, 'low');
                app.demod = filter(b, a , y);
                
            elseif app.demodulation == 2
                %Envelope Detector
                app.demod = abs(hilbert(app.mod));
                
            else
                %Square law
                mod_sq = app.mod.^2;
                fn = fs/2;
                [ ord, Wn ] = buttord(fm/fn, fc/fn, 1, 50);
                [b, a] = butter(ord, Wn, 'low');
                app.demod = filter(b, a , mod_sq);
                app.demod = app.demod/Ac;
                
                
            end
            
            demo = app.demod;   
            if  app.demodulation == 1
                plot(app.UIAxes, t, mess, '-r')
                app.UIAxes.XLim = [0, 10/fm];
        
                plot(app.UIAxes2, t, demo, '-r');
                app.UIAxes2.XLim = [0, 10/fm];
            elseif (app.demodulation == 2 && app.modulation == 1)
                plot(app.UIAxes, t, mess, '-r')
                app.UIAxes.XLim = [0, 10/fm];
        
                plot(app.UIAxes2, t, demo, '-r');
                app.UIAxes2.XLim = [0, 10/fm];
                [c, r] = app.rc_envelope();
                app.ResultsTextArea.Value = 'Capacitance is ' + string(c) + ' uF and Resistance is ' + string(r) + ' kilo Ohms.';
            elseif (app.demodulation == 3 && app.modulation == 1) 
                plot(app.UIAxes, t, mess, '-r')
                app.UIAxes.XLim = [0, 10/fm];
        
                plot(app.UIAxes2, t, demo, '-r');
                app.UIAxes2.XLim = [0, 10/fm];
            else
                app.ResultsTextArea.Value = 'Cannot perform demodulation using the currently selected technique';
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AMModulationDropDownLabel = uilabel(app.UIFigure);
            app.AMModulationDropDownLabel.Text = 'AM Modulation';
            app.AMModulationDropDown = uidropdown(app.UIFigure);
            app.AMModulationDropDown.Items = {'DSB-C', 'DSC-SC', 'SSB-USB', 'SSB-LSB'};
            app.AMModulationDropDown.ItemsData = [1 2 3 4];
            app.AMModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @AMModulationDropDownValueChanged, true);
            app.AMModulationDropDown.Value = 1;
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'Ideal lossless, noiseless channel', 'Lossy, noiseless channel', 'Lossless channel with Additive White Gaussian noise (AWGN)', 'Lossy, AWGN channel', ''};
            app.ChannelDropDown.ItemsData = [1 2 3 4];
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 1;
            app.DemodulationDropDownLabel = uilabel(app.UIFigure);
            app.DemodulationDropDownLabel.Text = 'Demodulation';
            app.DemodulationDropDown = uidropdown(app.UIFigure);
            app.DemodulationDropDown.Items = {'Synchronous detector', 'Envelope detector', 'Square-law detector'};
            app.DemodulationDropDown.ItemsData = [1 2 3];
            app.DemodulationDropDown.ValueChangedFcn = createCallbackFcn(app, @DemodulationDropDownValueChanged, true);
            app.DemodulationDropDown.Value = 1;
            app.InputFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.InputFilenameEditFieldLabel.Text = {'Input '; 'File name: '};
            app.InputFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.InputFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @InputFilenameEditFieldValueChanged, true);
            app.MessageButton = uibutton(app.UIFigure, 'push');
            app.MessageButton.ButtonPushedFcn = createCallbackFcn(app, @MessageButtonPushed, true);
            app.MessageButton.Text = 'Message';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.Editable = 'off';
            app.ResultsButton = uibutton(app.UIFigure, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            app.ModulatedSignalButton = uibutton(app.UIFigure, 'push');
            app.ModulatedSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ModulatedSignalButtonPushed, true);
            app.ModulatedSignalButton.Text = 'Modulated Signal';
            app.DemodulatedandcompareButton = uibutton(app.UIFigure, 'push');
            app.DemodulatedandcompareButton.ButtonPushedFcn = createCallbackFcn(app, @DemodulatedandcompareButtonPushed, true);
            app.DemodulatedandcompareButton.Text = 'Demodulated and  compare';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plots')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Frequency/Time')
            ylabel(app.UIAxes2, 'Amplitude (in F domain)/ Demodulated sig')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Comm_sys_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end